classdef Blade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesignerUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        BlattachseLabel        matlab.ui.control.Label
        t                      matlab.ui.control.NumericEditField
        BlattanzahlLabel       matlab.ui.control.Label
        number_blades          matlab.ui.control.NumericEditField
        ParameterLabel         matlab.ui.control.Label
        LngedBlattmLabel       matlab.ui.control.Label
        blade_radius           matlab.ui.control.NumericEditField
        Profils                matlab.ui.control.Table
        BladeDesignerLabel     matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        AirfoilLabel           matlab.ui.control.Label
        plus                   matlab.ui.control.Image
        minus                  matlab.ui.control.Image
        TipspeedratioLabel     matlab.ui.control.Label
        Lamda                  matlab.ui.control.NumericEditField
        HeightofTowerLabel     matlab.ui.control.Label
        H                      matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        DModellingLabel        matlab.ui.control.Label
        modellieren            matlab.ui.control.Image
        PreviewLabel           matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        Button                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DialogApp
    end
    
    
    properties (Access = public)
        foil_db_path="foil_imported.dat";
       foil_db=readtable("foils_imported.dat")
       lambda=7;
       R=50;
       z=6;
       H_tower=0;
       prof;
    end
    
    methods (Access = public)
        function add_blade(app,name,r,t)
            if length (app.prof)==0
                app.R
                app.prof=blade_root(app.R);
                if r>= 0.15*app.R
                    app.prof(3)=foil_transform(app.R,r,name,app.lambda,app.z, t,app.foil_db)
                else
                    disp('Profil ist zu nah an der Blattwurzel. Anderen Profilposition waehlen!')    
                end       
            else
                if check_existing(app.prof,r) == 0 & r>= 0.15*app.R
                    l=length(app.prof);
                    app.prof(l+1)=foil_transform(app.R,r,name,app.lambda,app.z, t,app.foil_db);
                    app.prof=bld_sort(app.prof);
                else
                    disp('Profil an dieser Blattposition existiert bereits oder ist zu nah an der Blattwurzel. Anderen Profilposition waehlen!')
                end
            end
            app.plus.Enable = 'on';
            blade_plot(app)
        end
      
        function  blade_plot(app)
            %%Plotet einen Satz profile in 3d
            l=length (app.prof)
            for n=1:l
                plot3(app.UIAxes ,app.prof(n).z, app.prof(n).y, app.prof(n).x,'k');
                hold( app.UIAxes, 'on' )
                axis (app.UIAxes, 'equal' ) 
                grid (app.UIAxes, 'on' ) 
                text(app.UIAxes ,app.prof(n).z(1), app.prof(n).y(1), app.prof(n).x(1)+1,app.prof(n).name)
            end
        hold( app.UIAxes, 'off' )
        end 
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: plus
        function plusImageClicked(app, event)
            app.Label.Text= string(app.foil_db.foil_name(1))
            app.DialogApp = BladeIn(app);
            app.plus.Enable = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BladeDesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesignerUIFigure = uifigure('Visible', 'off');
            app.BladeDesignerUIFigure.Name = 'Blade Designer';
            app.BladeDesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BladeDesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BlattachseLabel = uilabel(app.LeftPanel);
            app.BlattachseLabel.Text = 'Axis of Blade [%]';
            app.t = uieditfield(app.LeftPanel, 'numeric');
            app.t.Limits = [0 100];
            app.t.ValueDisplayFormat = '%.0f';
            app.BlattanzahlLabel = uilabel(app.LeftPanel);
            app.BlattanzahlLabel.Text = 'Number of Blade';
            app.number_blades = uieditfield(app.LeftPanel, 'numeric');
            app.number_blades.Limits = [0 Inf];
            app.number_blades.ValueDisplayFormat = '%.0f';
            app.ParameterLabel = uilabel(app.LeftPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.LngedBlattmLabel = uilabel(app.LeftPanel);
            app.LngedBlattmLabel.Text = 'Radius of Rotor [m]';
            app.blade_radius = uieditfield(app.LeftPanel, 'numeric');
            app.blade_radius.Limits = [0 Inf];
            app.blade_radius.ValueDisplayFormat = '%.2f';
            app.Profils = uitable(app.LeftPanel);
            app.Profils.ColumnName = {'Radius'; 'Airfoil'};
            app.Profils.RowName = {};
            app.Profils.ColumnSortable = [true false];
            app.BladeDesignerLabel = uilabel(app.LeftPanel);
            app.BladeDesignerLabel.Text = 'Blade Designer';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blatt.png';
            app.AirfoilLabel = uilabel(app.LeftPanel);
            app.AirfoilLabel.Text = 'Airfoil';
            app.plus = uiimage(app.LeftPanel);
            app.plus.ImageClickedFcn = createCallbackFcn(app, @plusImageClicked, true);
            app.plus.Tooltip = {'add an airfoil to the blade'};
            app.plus.ImageSource = 'plus.png';
            app.minus = uiimage(app.LeftPanel);
            app.minus.ImageSource = 'minus.png';
            app.TipspeedratioLabel = uilabel(app.LeftPanel);
            app.TipspeedratioLabel.Text = 'Tip-speed ratio';
            app.Lamda = uieditfield(app.LeftPanel, 'numeric');
            app.Lamda.Limits = [0 Inf];
            app.HeightofTowerLabel = uilabel(app.LeftPanel);
            app.HeightofTowerLabel.Text = 'Height of Tower [m]';
            app.H = uieditfield(app.LeftPanel, 'numeric');
            app.H.Limits = [0 Inf];
            app.H.ValueDisplayFormat = '%.2f';
            app.Label = uilabel(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.DModellingLabel = uilabel(app.RightPanel);
            app.DModellingLabel.Text = '3D Modelling';
            app.modellieren = uiimage(app.RightPanel);
            app.modellieren.ImageSource = 'model.png';
            app.PreviewLabel = uilabel(app.RightPanel);
            app.PreviewLabel.Text = 'Preview';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.BladeDesignerUIFigure, 'push');
            % Show the figure after all components are created
            app.BladeDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blade

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesignerUIFigure)
        end
    end
end